classdef ContracilityVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SearchLabel                     matlab.ui.control.Label
        SearchBar                       matlab.ui.control.EditField
        LaunchVideoButton               matlab.ui.control.Button
        CurrentDirectoryEditField       matlab.ui.control.EditField
        CurrentDirectoryEditFieldLabel  matlab.ui.control.Label
        LoadJobFileButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end


    properties (Access = private)
        filename % Name of the jobfile
        Player % App to the player
        pathname % Path to the jobfile
        JobFile % Table with all entries of the jobfile, and a column that 
                % will determine if they are displayed in the UITable, to
                % be used with the search bar.
    end
    
    properties (Access = public)
        selectedRow % Index of the selectedRow in the UITable
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                app.selectedRow = indices(1);
            else
                app.selectedRow = [];
            end
        end

        % Callback function
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Text = 'Pause';
            while app.PlayButton.Value
                app.FrameSlider.Enable = 'off';
                app.FrameSlider.Value = app.currentFrame;
                updateDeformationField(app);
                app.currentFrame = app.currentFrame + 1;
                if app.currentFrame > app.Nframes
                    app.currentFrame = 1;
                end
            end
            app.PlayButton.Text = 'Play';
            app.FrameSlider.Enable = 'on';
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
        end

        % Callback function
        function FrameSliderValueChanged(app, event)
            f = floor(app.FrameSlider.Value);
            app.currentFrame = f;
            updateDeformationField(app)
        end

        % Button pushed function: LoadJobFileButton
        function LoadJobFileButtonPushed(app, event)
            [f, p] = uigetfile({'*.csv'},'Pick a jobfile');
            drawnow;
            figure(app.UIFigure)
            if ~f
                return
            end
            A = readJobFile2(f, p);
            app.JobFile = A;
            app.JobFile.Visible = ones(height(A),1);
            app.UITable.Data = A;
            app.CurrentDirectoryEditField.Value = p;
        end

        % Button pushed function: LaunchVideoButton
        function LaunchVideoButtonPushed(app, event)
            app.Player= playerBeta3_DWS(app);
        end

        % Value changing function: SearchBar
        function SearchBarValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(changingValue)
                app.JobFile.Visible = contains(app.JobFile.Location,changingValue)|contains(app.JobFile.Alias,changingValue);
            else
                app.JobFile.Visible = ones(height(app.JobFile),1);
            end
            A = app.JobFile(app.JobFile.Visible==1,:);
            app.UITable.Data = removevars(A,{'Visible'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Contractility Visualization';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Alias'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.LoadJobFileButton = uibutton(app.UIFigure, 'push');
            app.LoadJobFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadJobFileButtonPushed, true);
            app.LoadJobFileButton.Icon = 'folder-solid.svg';
            app.LoadJobFileButton.Text = 'Load Job File';
            app.CurrentDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentDirectoryEditFieldLabel.Text = 'Current Directory';
            app.CurrentDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.LaunchVideoButton = uibutton(app.UIFigure, 'push');
            app.LaunchVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchVideoButtonPushed, true);
            app.LaunchVideoButton.Icon = 'play-solid.svg';
            app.LaunchVideoButton.Text = 'Launch Video';
            app.SearchBar = uieditfield(app.UIFigure, 'text');
            app.SearchBar.ValueChangingFcn = createCallbackFcn(app, @SearchBarValueChanging, true);
            app.SearchLabel = uilabel(app.UIFigure);
            app.SearchLabel.Text = 'Search:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContracilityVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end