classdef MakeAliasDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DoneButton         matlab.ui.control.Button
        UITableChecks      matlab.ui.control.Table
        InstructionsLabel  matlab.ui.control.Label
        UITableFileParts   matlab.ui.control.Table
        MakeAliasLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        DebugMode = 1; % Flag for debug
        CallingApp % Description
    end
    methods (Access = private)
        function refreshTables(app)
            LocationList = app.CallingApp.UITable.Data.Location;
            FilePartsTable = LocationList2FilePartsTable(LocationList);
            app.UITableFileParts.Data = FilePartsTable;
            T=[1:width(FilePartsTable)];
            app.UITableFileParts.ColumnName = "Loc." + T;
            levs = app.CallingApp.chosenLevelsForAlias;
            selected = false(height(FilePartsTable),1);
            selected(levs)=true(length(levs),1);
            app.UITableChecks.Data = selected;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            refreshTables(app); 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            delete(app)
        end

        % Cell edit callback: UITableChecks
        function UITableChecksCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % Update the aliases in the main app
            data = app.UITableFileParts.Data;
            selectedLevels = find(app.UITableChecks.Data);
            parts =  data(selectedLevels,:);
            aliases = {};
            for icol = 1 : size(data,2)
                aliases{icol} = strjoin(parts(:,icol),'_');
            end
            app.CallingApp.UITable.Data.Alias = aliases';
            app.CallingApp.chosenLevelsForAlias = selectedLevels;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MakeAliasLabel = uilabel(app.UIFigure);
            app.MakeAliasLabel.Text = 'Make Alias';
            app.UITableFileParts = uitable(app.UIFigure);
            app.UITableFileParts.ColumnName = {'Loc'};
            app.UITableFileParts.RowName = {};
            app.InstructionsLabel = uilabel(app.UIFigure);
            app.InstructionsLabel.Text = 'Select which levels will be used to generate aliases';
            app.UITableChecks = uitable(app.UIFigure);
            app.UITableChecks.ColumnName = {'Use'};
            app.UITableChecks.RowName = {};
            app.UITableChecks.ColumnEditable = true;
            app.UITableChecks.CellEditCallback = createCallbackFcn(app, @UITableChecksCellEdit, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MakeAliasDialogApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end