classdef SelectionFilterDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ActionDropDown        matlab.ui.control.DropDown
        entriesthatLabel      matlab.ui.control.Label
        FilterDropDown        matlab.ui.control.DropDown
        EditField             matlab.ui.control.EditField
        ApplyButton           matlab.ui.control.Button
        CloseButton           matlab.ui.control.Button
        SelectionFilterLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.UIFigure.Position(1) = app.CallingApp.UIFigure.Position(1)+app.CallingApp.UIFigure.Position(3);
            app.UIFigure.Position(2) = app.CallingApp.UIFigure.Position(2);
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function UIFigureWindowButtonDown(app, event)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            A = app.CallingApp.UITable.Data;
            str = A.Location;
            pat = app.EditField.Value;
            matches=contains(str,pat);
            if strcmp(app.FilterDropDown.Value,'do not contain')
                matches = ~matches;
            end
            rows = find(matches);
            switch app.ActionDropDown.Value
                case 'Select'
                    A(rows,:).Analyze = true(length(rows),1);
                case 'Clear'
                    A(rows,:).Analyze = false(length(rows),1);
            end
            %             head(A)
            app.CallingApp.UITable.Data = A;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ActionDropDown = uidropdown(app.UIFigure);
            app.ActionDropDown.Items = {'Select', 'Clear'};
            app.ActionDropDown.Value = 'Select';
            app.entriesthatLabel = uilabel(app.UIFigure);
            app.entriesthatLabel.Text = 'entries that';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'contain', 'do not contain'};
            app.FilterDropDown.Value = 'contain';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Tag = 'tagEditField';
            app.EditField.Tooltip = {'Enter text'};
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectionFilterLabel = uilabel(app.UIFigure);
            app.SelectionFilterLabel.Text = 'Selection Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectionFilterDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end