classdef playerBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        QuiverMenu                    matlab.ui.container.Menu
        ColorMenu                     matlab.ui.container.Menu
        Menu_3                        matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
        LineWidthMenu                 matlab.ui.container.Menu
        Menu_4                        matlab.ui.container.Menu
        HeadSizeMenu                  matlab.ui.container.Menu
        ColormapMenu_2                matlab.ui.container.Menu
        ShowLegendMenu                matlab.ui.container.Menu
        ColorSchemeMenu               matlab.ui.container.Menu
        grayMenu                      matlab.ui.container.Menu
        flagMenu                      matlab.ui.container.Menu
        ColorbarLimitsMenu            matlab.ui.container.Menu
        NextFrame                     matlab.ui.control.Button
        PrevFrame                     matlab.ui.control.Button
        PlaybackSpeedfpsSpinner       matlab.ui.control.Spinner
        PlaybackSpeedfpsSpinnerLabel  matlab.ui.control.Label
        CellBoundariesCheckBox        matlab.ui.control.CheckBox
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        QuiverDropDown                matlab.ui.control.DropDown
        QuiverDropDownLabel           matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        ArrowScaleSpinner             matlab.ui.control.Spinner
        ArrowScaleSpinnerLabel        matlab.ui.control.Label
        PlayButton                    matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Filename % Name of the jobfile
        Rootdir % Path to the jobfile
        VideoDir % Path to the images of the video
        MainDir % Path to the analysis results of that video
        Data % Struct with names, folder, and loaded bin data found inside MainDir
        CLimColormap % Color limits for the colormap options
        SelectedRow % Index of the SelectedRow in the UITable
        CurrentFrame % currentDisplayFrame
        hquiver % Handle to the quiver plot
        ArrowColor %
        ArrowWidth
        hboundaries % handles to each line of boundaries
        BoundaryColor
        himagesc % Handle to the imagesc that plots magnitude or cell labels
        Nframes % Number of frames of the movie
        AvailableFrames % Vector of the frames available (tvec) required to play the movie in real time
        tStart; % Keep track of 'play' time so that we update frame and play the movie in real time
        FrameRate; % MOVE LATER TO SOME UI interactive
        editableTags = {'tagFrameSlider'}; % Tags of UI that respond to mouse scroll
        CellposeMask % Mask from cellpose
        CellposeMaskRGB; % label2rgb(app.CellposeMask)
        UIAxes2
        UIAxes3 % Description
        rawVideoLocation % Location of the video
        rawVideo % Original video used for the analysis
    end

    methods (Access = private)
        function updateQuiverAxes(app)
            if ~strcmp(app.QuiverDropDown.Value,'none')
                set(app.hquiver,'Visible','on')
                curEntry = find(strcmp(app.QuiverDropDown.Value, app.QuiverDropDown.Items))-1; %-1 Because we should not count Items(1) = none
                xvec = app.Data(curEntry).xvec;
                yvec = app.Data(curEntry).yvec;
                set(app.hquiver,'XData',xvec);
                set(app.hquiver,'YData',yvec);
            else % quiver is set to 'none' turn off visibility
                set(app.hquiver,'Visible','off')
            end
        end
        function updateQuiverUV(app)
            if ~strcmp(app.QuiverDropDown.Value,'none')
                curEntry = find(strcmp(app.QuiverDropDown.Value, app.QuiverDropDown.Items))-1; %-1 Because we should not count Items(1) = none
                [~,idx] = min(abs(app.CurrentFrame- app.AvailableFrames));
                scl = app.ArrowScaleSpinner.Value;
                U = app.Data(curEntry).U(:,:,idx);
                V = app.Data(curEntry).V(:,:,idx);
                set(app.hquiver,'UData',scl*U);
                set(app.hquiver,'VData',scl*V);
            end
            drawnow;
        end
        function updateColormapAxis(app)
            switch app.ColormapDropDown.Value
                case 'none'
                    set(app.himagesc,'CData',[]);
                case 'cell_labels'
                    set(app.himagesc,'XData',[1:size(app.CellposeMaskRGB,2)])
                    set(app.himagesc,'YData',[1:size(app.CellposeMaskRGB,1)])
                    set(app.himagesc,'CData',app.CellposeMaskRGB);
                case 'original_video'
                    set(app.UIFigure,'Colormap',gray)
                    set(app.himagesc,'XData',[1:size(app.rawVideo,2)])
                    set(app.himagesc,'YData',[1:size(app.rawVideo,1)])
                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value, app.ColormapDropDown.Items))-1; %-1 Because we should not count Items(1) = none
                    [~,idx] = min(abs(app.CurrentFrame- app.AvailableFrames));
                    C = app.Data(curEntry);
                    set(app.himagesc,'XData',C.xvec)
                    set(app.himagesc,'YData',C.yvec)
            end
            drawnow;
        end
        function updateColormap(app)
            switch app.ColormapDropDown.Value
                case 'none'

                case 'cell_labels'

                case 'original_video'
                    set(app.himagesc,'CData',app.rawVideo(:,:,app.CurrentFrame));
                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value, app.ColormapDropDown.Items))-1; %-1 Because we should not count Items(1) = none
                    [~,idx] = min(abs(app.CurrentFrame- app.AvailableFrames));
                    C = app.Data(curEntry);
                    set(app.himagesc,'CData',C.MAG(:,:,idx));
            end
            drawnow;
        end
        function loadData(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Indeterminate',"on");
            % Load vector data
            filelist = dir(fullfile(app.MainDir, '**\*.bin'));  %get list of files and folders in any subfolder
            for idata = 1 : length(filelist)
                app.Data(idata).Name = filelist(idata).name;
                app.Data(idata).Folder = filelist(idata).folder;
                d.Message = ['Loading ', app.Data(idata).Name];
                drawnow;
                % Pop-up dialog
                fname = fullfile(app.Data(idata).Folder,app.Data(idata).Name);
                [X,Y,T,U,V] = readPIV_bin(fname);
                app.Data(idata).xvec = unique(X(:));
                app.Data(idata).yvec = unique(Y(:));
                app.Data(idata).tvec = unique(T(:));
                app.Data(idata).U = U;
                app.Data(idata).V = V;
                app.Data(idata).MAG = sqrt(U.^2+V.^2);
                app.Data(idata).colormap = 'flag';
                app.Data(idata).caxis = quantile(app.Data(idata).MAG(:),[0.01 0.99]);
            end
            % Refresh dropdown buttons
            dum = strrep({app.Data.Name},'.bin','');
            app.QuiverDropDown.Items = ['none', dum];
            app.QuiverDropDown.Value = dum(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)
            app.ColormapDropDown.Items = ['none', cellfun(@(c)['mag_', c], dum,'uni',false)];
            app.ColormapDropDown.Value = app.ColormapDropDown.Items(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)

            % Load Mask data
            filelist = dir(fullfile(app.MainDir, '**\*cp_masks*'));  %get list of files and folders in any subfolder
            if ~isempty(filelist)
                d.Message = ['Loading cell labels'];
                drawnow;
                im = imread(fullfile(filelist.folder,filelist.name));
                app.CellposeMask = im;
                app.CellposeMaskRGB = label2rgb(im,'lines','k','shuffle');
                app.ColormapDropDown.Items = [app.ColormapDropDown.Items,'cell_labels'];
            end

            % Load Original Video
            d.Message = ['Loading raw video'];
            drawnow;
            [app.rawVideo] = imageLoader(app.rawVideoLocation,'tif');
            app.ColormapDropDown.Items = [app.ColormapDropDown.Items,'original_video'];
            app.ColormapDropDown.Value = app.ColormapDropDown.Items(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)
            close(d) % Close loading dialog

        end

        function initializeAxes(app)
            dat = app.Data(end); % assume that all data shares the same axes limits, so we can always use entry (1)
            dx = dat.xvec(2)-dat.xvec(1);
            dy = dat.yvec(2)-dat.yvec(1);
            app.Nframes = dat.tvec(end);
            app.FrameSlider.Limits = [1,app.Nframes];
            app.AvailableFrames = dat.tvec;
            set(app.himagesc,'CLim',[0 max(dat.MAG(:))]);
            set(app.UIAxes,'XLim',[1, dat.xvec(end)+dx]);
            set(app.UIAxes,'YLim',[1, dat.yvec(end)+dy]);
            set(app.UIAxes,'xcolor','none');
            set(app.UIAxes,'ycolor','none');
            set(app.UIAxes2,'XLim',[1, dat.xvec(end)+dx]);
            set(app.UIAxes2,'YLim',[1, dat.yvec(end)+dy]);
            set(app.UIAxes2,'xcolor','none');
            set(app.UIAxes2,'ycolor','none');
            set(app.UIAxes3,'XLim',[1, dat.xvec(end)+dx]);
            set(app.UIAxes3,'YLim',[1, dat.yvec(end)+dy]);
            set(app.UIAxes3,'xcolor','none');
            set(app.UIAxes3,'ycolor','none');
        end

        function initializePlot(app)
            % Always initialize with raw video
            if ~isempty(app.rawVideo)
                app.himagesc  = imagesc(app.UIAxes,app.rawVideo(:,:,1));
                app.CLimColormap = double(quantile(app.rawVideo(:,:,1),[0.05 0.99],"all"));
                app.CLimColormap
                caxis(app.UIAxes,app.CLimColormap)
            else % if not, then use the most resoluted colormap, which matches the quiverplot being ploted
                app.himagesc  = imagesc(app.UIAxes,app.Data(end).MAG(:,:,1),...
                    'XData',app.Data(end).xvec,'YData',app.Data(end).yvec);
            end
            % Load outlines
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.DataAspectRatio = app.UIAxes.DataAspectRatio;
            app.UIAxes2.PlotBoxAspectRatio = app.UIAxes.PlotBoxAspectRatio;
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.GridColor = [0.15 0.15 0.15];
            app.UIAxes2.Position = app.UIAxes.Position;
            app.UIAxes2.Color='none';
            app.UIAxes2.YDir = 'reverse';
            cellIDs = unique(app.CellposeMask(app.CellposeMask~=0)');
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Loading cell boundaries','Indeterminate',"on");
            drawnow
            hold(app.UIAxes2,'on');
            app.UIAxes2.XLim = app.UIAxes.XLim;
            app.UIAxes2.YLim = app.UIAxes.YLim;
            mask = boundarymask(app.CellposeMask);
            app.hboundaries = imagesc(app.UIAxes2,mask);
            app.hboundaries.AlphaData = (mask==1);
            colormap(app.UIAxes2, [0 0 0; app.BoundaryColor]);
            %             for icell = cellIDs
            %                 B = bwboundaries(app.CellposeMask==icell);
            %                 b = B{1};
            %                 app.hboundaries(icell)=plot(app.UIAxes2,b(:,2),b(:,1),'color',app.BoundaryColor,'linewidth',app.BoundaryLineWidth);
            %                 drawnow
            %             end
            hold(app.UIAxes2,'off');
            delete(d)

            curEntry = find(strcmp(app.QuiverDropDown.Value, app.QuiverDropDown.Items))-1; %-1 Because we should not count Items(1) = none
            [X,Y] = meshgrid(app.Data(curEntry).xvec,app.Data(curEntry).yvec);
            scl = app.ArrowScaleSpinner.Value;
            U = app.Data(curEntry).U(:,:,app.CurrentFrame);
            V = app.Data(curEntry).V(:,:,app.CurrentFrame);

            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.DataAspectRatio = app.UIAxes.DataAspectRatio;
            app.UIAxes3.PlotBoxAspectRatio = app.UIAxes.PlotBoxAspectRatio;
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.GridColor = [0.15 0.15 0.15];
            app.UIAxes3.Position = app.UIAxes.Position;
            app.UIAxes3.Color='none';
            app.UIAxes3.YDir = 'reverse';
            app.hquiver = quiver(app.UIAxes3,X,Y,scl*U(:,:,1),scl*V(:,:,1),...
                0,'Color',app.ArrowColor,'LineWidth',app.ArrowWidth);
            linkaxes([app.UIAxes,app.UIAxes2,app.UIAxes3])
            colorbar(app.UIAxes,'Visible','off')
            app.UIAxes.Position = app.UIAxes3.Position;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.MainDir = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220705_133430\output\Pretreatment_Middle_Well__E_002_Tritc';
            %             app.rawVideoLocation = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\Pretreatment_Middle\Well__E_002\Tritc';
            app.MainDir = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220712_164803_multipass\output\Pretreatment_Middle_Well__E_007_Tritc';
            %             app.MainDir = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220711_121055';
            app.rawVideoLocation = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\Pretreatment_Middle\Well__E_007\Tritc';
            %             app.MainDir = 'E:\20220405_Illumination\DyeTest_2_I100_20220405121357\contractility_run_20220712_005045\output\Well__E_003_MitoTrackerDeepRed';
            %             app.rawVideoLocation = 'E:\20220405_Illumination\DyeTest_2_I100_20220405121357\scan\Well__E_003\MitoTrackerDeepRed';

            app.CurrentFrame = 1;
            app.FrameRate = app.PlaybackSpeedfpsSpinner.Value;
            app.BoundaryColor = [1 0 0];
            app.ArrowColor =  [161, 102, 255]/255;
            app.ArrowWidth = 0.75;
            loadData(app);
            % Refresh X-Y-time and color axes limits
            initializeAxes(app)
            initializePlot(app)

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            f = floor(app.FrameSlider.Value);
            app.CurrentFrame = f;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            if app.PlayButton.Value
                last_frame = app.CurrentFrame; % Get the last frame plotted,
                % this is to avoid restarting video at frame 1 when toggling between pause/play
                app.tStart = tic;
                app.FrameSlider.Enable = 'off';
                app.QuiverDropDown.Enable = 'off';
                app.ColormapDropDown.Enable = 'off';
                app.PlayButton.Text = 'Pause';
            end
            while app.PlayButton.Value
                app.FrameSlider.Value = app.CurrentFrame;
                tEnd = toc(app.tStart);
                theo_frame = 1 + floor(mod((last_frame + tEnd*app.FrameRate),app.Nframes));
                app.CurrentFrame = theo_frame;
                app.FrameSlider.Value = theo_frame;
                updateQuiverUV(app);
                updateColormap(app);
                drawnow;
            end
            app.PlayButton.Text = 'Play';
            app.FrameSlider.Enable = 'on';
            app.QuiverDropDown.Enable = 'on';
            app.ColormapDropDown.Enable = 'on';
        end

        % Value changed function: QuiverDropDown
        function QuiverDropDownValueChanged(app, event)
            value = app.QuiverDropDown.Value;
            updateQuiverAxes(app);
            updateQuiverUV(app);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            updateColormapAxis(app);
            updateColormap(app);
        end

        % Value changed function: PlaybackSpeedfpsSpinner
        function PlaybackSpeedfpsSpinnerValueChanged(app, event)
            value = app.PlaybackSpeedfpsSpinner.Value;
            app.FrameRate = value;
        end

        % Value changed function: CellBoundariesCheckBox
        function CellBoundariesCheckBoxValueChanged(app, event)
            value = app.CellBoundariesCheckBox.Value;
            if value
                set(app.hboundaries,'Visible','on');
            else
                set(app.hboundaries,'Visible','off');
            end
        end

        % Value changed function: ArrowScaleSpinner
        function ArrowScaleSpinnerValueChanged(app, event)
            value = app.ArrowScaleSpinner.Value;
            updateQuiverAxes(app);
            updateQuiverUV(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            pos = app.UIFigure.CurrentPoint;
            hFound=[];
            for n=1:length(app.editableTags)
                hTag = findobj(app.UIFigure,'Tag', app.editableTags{n});
                hPos = getpixelposition(hTag,true);  % recursive to find absoulute position
                if pos(1)>hPos(1) && pos(1)<hPos(1)+hPos(3) && pos(2)>hPos(2) && pos(2)<hPos(2)+hPos(4)
                    hFound = hTag;
                    break
                end
            end
            if isempty(hFound)
                return
            end
            switch hFound.Type
                case 'uispinner'
                    hFound.Value = hFound.Value - verticalScrollCount*hFound.Step;
                case 'uislider'
                    step = 1;
                    val = hFound.Value - verticalScrollCount*step;
                    if val>=hFound.Limits(1) && val<=hFound.Limits(2)
                        hFound.Value = hFound.Value + verticalScrollCount*step;
                        app.CurrentFrame = round(hFound.Value);
                    end
                otherwise
            end
            updateQuiverUV(app);
            updateColormap(app);
        end

        % Menu selected function: ShowLegendMenu
        function ShowLegendMenuSelected(app, event)
            if strcmp(app.ShowLegendMenu.Checked,'off')
                app.ShowLegendMenu.Checked = 'on';
                colorbar(app.UIAxes,'Visible','on')
                drawnow
            else
                app.ShowLegendMenu.Checked = 'off';
                colorbar(app.UIAxes,'Visible','off')
                drawnow
            end

        end

        % Button pushed function: NextFrame
        function NextFrameButtonPushed(app, event)
            app.CurrentFrame = app.CurrentFrame+1;
            app.FrameSlider.Value = app.CurrentFrame;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Button pushed function: PrevFrame
        function PrevFrameButtonPushed(app, event)
            app.CurrentFrame = app.CurrentFrame-1;
            app.FrameSlider.Value = app.CurrentFrame;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    NextframeButtonPushed(app, event);
                case 'leftarrow'
                    PrevframeButtonPushed(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.QuiverMenu = uimenu(app.UIFigure);
            app.QuiverMenu.Text = 'Quiver';
            app.ColorMenu = uimenu(app.QuiverMenu);
            app.ColorMenu.Text = 'Color';
            app.Menu_3 = uimenu(app.ColorMenu);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ColorMenu);
            app.Menu2_3.Text = 'Menu2';
            app.LineWidthMenu = uimenu(app.QuiverMenu);
            app.LineWidthMenu.Text = 'Line Width';
            app.Menu_4 = uimenu(app.LineWidthMenu);
            app.Menu_4.Text = 'Menu';
            app.HeadSizeMenu = uimenu(app.QuiverMenu);
            app.HeadSizeMenu.Text = 'Head Size';
            app.ColormapMenu_2 = uimenu(app.UIFigure);
            app.ColormapMenu_2.Text = 'Colormap';
            app.ShowLegendMenu = uimenu(app.ColormapMenu_2);
            app.ShowLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowLegendMenuSelected, true);
            app.ShowLegendMenu.Text = 'Show Legend';
            app.ColorSchemeMenu = uimenu(app.ColormapMenu_2);
            app.ColorSchemeMenu.Text = 'Color Scheme';
            app.grayMenu = uimenu(app.ColorSchemeMenu);
            app.grayMenu.Text = 'gray';
            app.flagMenu = uimenu(app.ColorSchemeMenu);
            app.flagMenu.Text = 'flag';
            app.ColorbarLimitsMenu = uimenu(app.ColormapMenu_2);
            app.ColorbarLimitsMenu.Text = 'Colorbar Limits';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'gray')
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.ArrowScaleSpinnerLabel = uilabel(app.UIFigure);
            app.ArrowScaleSpinnerLabel.Text = {'Arrow Scale'; ''};
            app.ArrowScaleSpinner = uispinner(app.UIFigure);
            app.ArrowScaleSpinner.Step = 0.1;
            app.ArrowScaleSpinner.Limits = [0 Inf];
            app.ArrowScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowScaleSpinnerValueChanged, true);
            app.ArrowScaleSpinner.Value = 3;
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Tag = 'tagFrameSlider';
            app.FrameSlider.Value = 1;
            app.QuiverDropDownLabel = uilabel(app.UIFigure);
            app.QuiverDropDownLabel.Text = 'Quiver';
            app.QuiverDropDown = uidropdown(app.UIFigure);
            app.QuiverDropDown.Items = {'none'};
            app.QuiverDropDown.ValueChangedFcn = createCallbackFcn(app, @QuiverDropDownValueChanged, true);
            app.QuiverDropDown.Value = 'none';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'none'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'none';
            app.CellBoundariesCheckBox = uicheckbox(app.UIFigure);
            app.CellBoundariesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CellBoundariesCheckBoxValueChanged, true);
            app.CellBoundariesCheckBox.Text = 'Cell Boundaries';
            app.CellBoundariesCheckBox.Value = true;
            app.PlaybackSpeedfpsSpinnerLabel = uilabel(app.UIFigure);
            app.PlaybackSpeedfpsSpinnerLabel.Text = 'Playback Speed (fps)';
            app.PlaybackSpeedfpsSpinner = uispinner(app.UIFigure);
            app.PlaybackSpeedfpsSpinner.Step = 0.1;
            app.PlaybackSpeedfpsSpinner.Limits = [0 Inf];
            app.PlaybackSpeedfpsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedfpsSpinnerValueChanged, true);
            app.PlaybackSpeedfpsSpinner.Value = 30;
            app.PrevFrame = uibutton(app.UIFigure, 'push');
            app.PrevFrame.ButtonPushedFcn = createCallbackFcn(app, @PrevFrameButtonPushed, true);
            app.PrevFrame.Text = '<<';
            app.NextFrame = uibutton(app.UIFigure, 'push');
            app.NextFrame.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrame.Text = '>>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playerBeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end