classdef playerBeta5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AppereanceMenu                matlab.ui.container.Menu
        ArrowColorMenu                matlab.ui.container.Menu
        ArrowLineWidthMenu            matlab.ui.container.Menu
        ColormapEditorMenu            matlab.ui.container.Menu
        ShowLegendMenu                matlab.ui.container.Menu
        CellBoundaryColorMenu         matlab.ui.container.Menu
        SaveVideoButton               matlab.ui.control.Button
        NextFrame                     matlab.ui.control.Button
        PrevFrame                     matlab.ui.control.Button
        PlaybackSpeedfpsSpinner       matlab.ui.control.Spinner
        PlaybackSpeedfpsSpinnerLabel  matlab.ui.control.Label
        CellBoundariesCheckBox        matlab.ui.control.CheckBox
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        QuiverDropDown                matlab.ui.control.DropDown
        QuiverDropDownLabel           matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        ArrowScaleSpinner             matlab.ui.control.Spinner
        ArrowScaleSpinnerLabel        matlab.ui.control.Label
        PlayButton                    matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Filename % Name of the jobfile
        Rootdir % Path to the jobfile
        MainDir % Path to the analysis results of that video
        VectorData % Struct with names, folder, and loaded bin data found inside MainDir
        ColormapData % Struct with names, folder, and loaded bin data found inside MainDir
        CurrentFrame % currentDisplayFrame
        hquiver % Handle to the quiver plot
        ArrowColor %
        ArrowLineWidth %
        hboundaries % handles to each line of boundaries
        BoundaryColor
        himagesc % Handle to the imagesc that plots magnitude or cell labels
        Nframes % Number of frames of the movie
        tStart; % Keep track of 'play' time so that we update frame and play the movie in real time
        FrameRate; % Play Speed
        editableTags = {'tagFrameSlider'}; % Tags of UI that respond to mouse scroll
        CellposeMask % Mask from cellpose
        CellposeMaskRGB; % label2rgb(app.CellposeMask)
        UIAxes2
        UIAxes3 % Description
        VideoDir % Location of the video
        OutputVideoResolution = [512 512] % Description
        CallingApp % The app that launched the player
        isDedrifted % True if image alignment (de-drifting) has been performed. Will give the appeareance of images being "cropped"
    end

    methods (Access = private)
        function updateQuiverAxes(app)
            if ~strcmp(app.QuiverDropDown.Value,'none')
                set(app.hquiver,'Visible','on')
                % Moving this code now because with the drift correction
                % now xvec and yvec can change in time
            else % quiver is set to 'none' turn off visibility
                set(app.hquiver,'Visible','off')
            end
        end
        function updateQuiverUV(app)
            if ~strcmp(app.QuiverDropDown.Value,'none')
                curEntry = find(strcmp(app.QuiverDropDown.Value, ...
                    strrep({app.VectorData.Name},'.bin','')));
                C = app.VectorData(curEntry);
                [~,idx] = min(abs(app.CurrentFrame- C.tvec));
                scl = app.ArrowScaleSpinner.Value;
                U = C.U(:,:,idx);
                V = C.V(:,:,idx);
                set(app.hquiver,'UData',scl*U);
                set(app.hquiver,'VData',scl*V);
                % Drift
                xvec = C.xvec + C.xdrift(idx) * (1-app.isDedrifted);
                yvec = C.yvec + C.ydrift(idx) * (1-app.isDedrifted);
                set(app.hquiver,'XData',xvec);
                set(app.hquiver,'YData',yvec);
            end
            drawnow;
        end
        function updateColormapAxis(app)
            switch app.ColormapDropDown.Value
                case 'none'
                    set(app.himagesc,'CData',[]);
                case 'cell_labels'
                    set(app.himagesc,'XData',[1:size(app.CellposeMaskRGB,2)])
                    set(app.himagesc,'YData',[1:size(app.CellposeMaskRGB,1)])
                    set(app.himagesc,'CData',app.CellposeMaskRGB);
                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value,...
                        strrep({app.ColormapData.Name},'.bin','')));
                    C = app.ColormapData(curEntry);
                    [~,idx] = min(abs(app.CurrentFrame- C.tvec));
                    set(app.UIAxes,'Colormap',C.colormap)
                    set(app.UIAxes,'CLim',C.caxis)
                    set(app.himagesc,'XData',C.xvec + C.xdrift(idx) * (1-app.isDedrifted)) 
                    set(app.himagesc,'YData',C.yvec + C.ydrift(idx) * (1-app.isDedrifted))
                    set(app.himagesc,'CData',C.MAG(:,:,idx))
            end
            colormap(app.UIAxes2, [0 0 0; app.BoundaryColor]);
            drawnow;
        end
        function updateColormap(app)
            switch app.ColormapDropDown.Value
                case 'none'
                    % nothing to do
                case 'cell_labels'
                    % nothing to do
                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value, ...
                        strrep({app.ColormapData.Name},'.bin','')));
                    C = app.ColormapData(curEntry);
                    [~,idx] = min(abs(app.CurrentFrame- C.tvec));
                    set(app.himagesc,'CData',C.MAG(:,:,idx));
            end
            drawnow;
        end
        function loadData(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Indeterminate',"on");
            app.VectorData = struct;
            vCount = 1;
            app.ColormapData = struct;
            cCount = 1;
            
            % Load PIV data
            filelist = dir(fullfile(app.MainDir, '**\*.bin'));  %get list of files and folders in any subfolder
            pivfilelist = filelist(~cellfun(@isempty,regexp({filelist.name},'deformations_pass')));
            for idata = 1 : length(pivfilelist)
                app.VectorData(vCount).Name = pivfilelist(idata).name;
                app.VectorData(vCount).Folder = pivfilelist(idata).folder;
                d.Message = ['Loading ', app.VectorData(vCount).Name];
                drawnow;
                % Pop-up dialog
                fname = fullfile(app.VectorData(vCount).Folder,...
                    app.VectorData(vCount).Name);
                
                [xvec,yvec,tvec,U,V,xdrift,ydrift] = readPIV_bin(fname);
                app.VectorData(vCount).xvec = xvec;
                app.VectorData(vCount).yvec = yvec;
                app.VectorData(vCount).tvec = tvec;
                app.VectorData(vCount).xdrift =  xdrift;
                app.VectorData(vCount).ydrift =  ydrift;
                app.VectorData(vCount).U = U;
                app.VectorData(vCount).V = V;
                vCount = vCount + 1;

                app.ColormapData(cCount).Name = ['divergence_',pivfilelist(idata).name];
                app.ColormapData(cCount).Folder = pivfilelist(idata).folder;
                app.ColormapData(cCount).xvec = xvec;
                app.ColormapData(cCount).yvec = yvec;
                app.ColormapData(cCount).tvec = tvec;
                [X,Y] = meshgrid(xvec,yvec);
                app.ColormapData(cCount).MAG = divergence_rik(X,Y,U,V);
                app.ColormapData(cCount).colormap = turbo;
                app.ColormapData(cCount).caxis = quantile(app.ColormapData(cCount).MAG(:),[0.01 0.99]);
                app.ColormapData(cCount).xdrift =  xdrift;
                app.ColormapData(cCount).ydrift =  ydrift;
                cCount = cCount + 1;
                
            end

            tfmfilelist = filelist(~cellfun(@isempty,regexp({filelist.name},'traction_stresses')));
            for idata = 1 : length(tfmfilelist)
                app.VectorData(vCount).Name = tfmfilelist(idata).name;
                app.VectorData(vCount).Folder = tfmfilelist(idata).folder;
                % Pop-up dialog
                d.Message = ['Loading ', app.VectorData(vCount).Name];
                drawnow;

                fname = fullfile(app.VectorData(vCount).Folder,...
                    app.VectorData(vCount).Name);
                
                [xvec,yvec,tvec,U,V,xdrift,ydrift] = readPIV_bin(fname);
                app.VectorData(vCount).xvec = xvec;
                app.VectorData(vCount).yvec = yvec;
                app.VectorData(vCount).tvec = tvec;
                app.VectorData(vCount).xdrift =  xdrift;
                app.VectorData(vCount).ydrift =  ydrift;
                app.VectorData(vCount).U = U;
                app.VectorData(vCount).V = V;
                vCount = vCount + 1;

                app.ColormapData(cCount).Name = ['magnitude_',tfmfilelist(idata).name];
                app.ColormapData(cCount).Folder = tfmfilelist(idata).folder;
                app.ColormapData(cCount).xvec = xvec;
                app.ColormapData(cCount).yvec = yvec;
                app.ColormapData(cCount).tvec = tvec;
                app.ColormapData(cCount).MAG = sqrt(U.^2+V.^2);
                app.ColormapData(cCount).colormap = turbo;
                app.ColormapData(cCount).caxis = quantile(app.ColormapData(cCount).MAG(:),[0.01 0.99]);
                app.ColormapData(cCount).xdrift =  xdrift;
                app.ColormapData(cCount).ydrift =  ydrift;
                cCount = cCount + 1;
            end
            msmfilelist = filelist(~cellfun(@isempty,regexp({filelist.name},'monolayer_stresses')));
            for idata = 1 : length(tfmfilelist)
                app.ColormapData(cCount).Name = msmfilelist(idata).name;
                app.ColormapData(vCount).Folder = msmfilelist(idata).folder;
                d.Message = ['Loading ', app.ColormapData(cCount).Name];
                
                % Pop-up dialog
                fname = fullfile(app.ColormapData(cCount).Folder,...
                    app.ColormapData(cCount).Name);
                
                [xvec,yvec,tvec,SXX,SYY,SXY,xdrift,ydrift] = readMSM_bin(fname);
                app.ColormapData(cCount).Name = ['internal_tension_',msmfilelist(idata).name];
                app.ColormapData(cCount).Folder = msmfilelist(idata).folder;
                [X,Y] = meshgrid(xvec,yvec);
                app.ColormapData(cCount).MAG = SXX+SYY;
                app.ColormapData(cCount).colormap = turbo;
                app.ColormapData(cCount).caxis = quantile(app.ColormapData(cCount).MAG(:),[0.01 0.99]);
                app.ColormapData(cCount).xvec = xvec;
                app.ColormapData(cCount).yvec = yvec;
                app.ColormapData(cCount).tvec = tvec;
                app.ColormapData(cCount).xdrift =  xdrift;
                app.ColormapData(cCount).ydrift =  ydrift;
                cCount = cCount + 1;
            end

            
            % Refresh dropdown buttons
            dum = strrep({app.VectorData.Name},'.bin','');
            app.QuiverDropDown.Items = ['none', dum];
            app.QuiverDropDown.Value = dum(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)
            dum = strrep({app.ColormapData.Name},'.bin','');
            app.ColormapDropDown.Items = ['none', dum];
            app.ColormapDropDown.Value = app.ColormapDropDown.Items(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)

            % Load Mask data
            filelist2 = dir(fullfile(app.MainDir, '**\*cp_masks*'));  %get list of files and folders in any subfolder
            if ~isempty(filelist2)
                d.Message = ['Loading cell labels'];
                drawnow;
                im = imread(fullfile(filelist2.folder,filelist2.name));
                app.CellposeMask = im;
                app.CellposeMaskRGB = label2rgb(im,'lines','k','shuffle');
                app.ColormapDropDown.Items = [app.ColormapDropDown.Items,'cell_labels'];
            end

            % Load Original Video
            
            [videoFound,newDir] = check_data_location(app.VideoDir);
            if videoFound
                if ~strcmp(newDir,app.VideoDir)
                    f = msgbox({"Video not found in location as listed in jobfile but was found in: ";newDir},"Video not found","warn");
                    app.VideoDir = newDir;
                end
                d.Message = ['Loading raw video'];
                drawnow;
                app.ColormapData(cCount).Name = 'original_video';
                app.ColormapData(cCount).Folder = app.VideoDir;
                app.ColormapData(cCount).MAG = imageLoader(app.VideoDir,'tif','noparallel');
                driftfile = filelist(~cellfun(@isempty,regexp({filelist.name},'image_drift')));
                if ~isempty(driftfile)
                    result = driftCorrectionMode(app);
                    if result
                        fname = fullfile(driftfile(1).folder,driftfile(1).name);
                        app.isDedrifted = 1;
                        d.Message = ['Aligning images'];
                        app.ColormapData(cCount).MAG = align_images(app.ColormapData(cCount).MAG,fname);
                    end
                end
                [ny,nx,nt] = size(app.ColormapData(cCount).MAG);
                app.ColormapData(cCount).xvec = 1:nx;
                app.ColormapData(cCount).yvec = 1:ny;
                app.ColormapData(cCount).tvec = 1:nt;
                app.ColormapData(cCount).xdrift = 0*(1:nt);
                app.ColormapData(cCount).ydrift = 0*(1:nt);
                app.ColormapData(cCount).caxis = double(quantile(app.ColormapData(cCount).MAG(:,:,1),[0.05 0.99],"all"));
                app.ColormapData(cCount).colormap = gray;
                cCount = cCount + 1;
                app.ColormapDropDown.Items = [app.ColormapDropDown.Items,'original_video'];
                
            else
                f = msgbox("Video not found.","Video not found","error");
            end
            app.ColormapDropDown.Value = app.ColormapDropDown.Items(end); % By default we will set the dropdown of the arrows to the last data entry (usually the most refined PIV)
            close(d) % Close loading dialog
            propListener  = addlistener(app.UIAxes,'Colormap','PostSet',@(src,evnt)colormapEditorChanged(app));
            propListener2 = addlistener(app.UIAxes,'CLim','PostSet',@(src,evnt)colormapLimitsChanged(app));

        end

        function initializeAxes(app)
            dat = app.ColormapData(end); % assume that all data shares the same axes limits, so we can always use entry (1)
            app.Nframes = dat.tvec(end);
            app.FrameSlider.Limits = [1,app.Nframes];
            set(app.himagesc,'CLim',[0 max(dat.MAG(:))]);
            set(app.UIAxes,'XLim',[1, dat.xvec(end)]);
            set(app.UIAxes,'YLim',[1, dat.yvec(end)]);
            set(app.UIAxes,'xcolor','none');
            set(app.UIAxes,'ycolor','none');
            colorbar(app.UIAxes,'Visible','off')

            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.DataAspectRatio = app.UIAxes.DataAspectRatio;
            app.UIAxes2.PlotBoxAspectRatio = app.UIAxes.PlotBoxAspectRatio;
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Position = app.UIAxes.Position;
            app.UIAxes2.Color='none';
            app.UIAxes2.YDir = 'reverse';
            set(app.UIAxes2,'XLim',app.UIAxes.XLim);
            set(app.UIAxes2,'YLim',app.UIAxes.YLim);
            set(app.UIAxes2,'xcolor','none');
            set(app.UIAxes2,'ycolor','none');

            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.DataAspectRatio = app.UIAxes.DataAspectRatio;
            app.UIAxes3.PlotBoxAspectRatio = app.UIAxes.PlotBoxAspectRatio;
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.XLim = app.UIAxes.XLim;
            app.UIAxes3.YLim = app.UIAxes.YLim;
            app.UIAxes3.GridColor = [0.15 0.15 0.15];
            app.UIAxes3.Position = app.UIAxes.Position;
            app.UIAxes3.Color='none';
            app.UIAxes3.YDir = 'reverse';
            set(app.UIAxes3,'XLim',[1, dat.xvec(end)]);
            set(app.UIAxes3,'YLim',[1, dat.yvec(end)]);
            set(app.UIAxes3,'xcolor','none');
            set(app.UIAxes3,'ycolor','none');

            linkaxes([app.UIAxes,app.UIAxes2,app.UIAxes3])
            app.UIAxes.Position = app.UIAxes3.Position;
        end

        function initializePlot(app)
            % Always initialize with raw video
            isVideo = strcmp({app.ColormapData.Name},'original_video');
            if any(isVideo)
                ind = find(isVideo);
                app.himagesc  = imagesc(app.UIAxes,app.ColormapData(ind).MAG(:,:,1));
                set(app.UIAxes,'CLim',app.ColormapData(ind).caxis, ...
                    'XLim',app.ColormapData(ind).xvec([1,end]),'YLim',app.ColormapData(ind).yvec([1,end]))
                %                 set(app.UIFigure,'Colormap',app.Data(ind).colormap)
            else % if not, then use the most resoluted colormap, which matches the quiverplot being ploted
                app.himagesc  = imagesc(app.UIAxes,app.ColormapData(end).MAG(:,:,1),...
                    'XData',app.ColormapData(end).xvec,'YData',app.ColormapData(end).yvec);
            end
            % Initialize cell boundaries
            mask = boundarymask(app.CellposeMask);
            app.hboundaries = imagesc(app.UIAxes2,mask);
            app.hboundaries.AlphaData = (mask==1);
            colormap(app.UIAxes2, [0 0 0; app.BoundaryColor]);

            % Initialize quiver
            curEntry = find(strcmp(app.QuiverDropDown.Value, ...
                strrep({app.VectorData.Name},'.bin','')));
            [X,Y] = meshgrid(app.VectorData(curEntry).xvec + ...
                app.VectorData(curEntry).xdrift(app.CurrentFrame) * (1-app.isDedrifted),...
                app.VectorData(curEntry).yvec + ...
                app.VectorData(curEntry).ydrift(app.CurrentFrame) * (1-app.isDedrifted));
            scl = app.ArrowScaleSpinner.Value;
            U = app.VectorData(curEntry).U(:,:,app.CurrentFrame);
            V = app.VectorData(curEntry).V(:,:,app.CurrentFrame);
            app.hquiver = quiver(app.UIAxes3,X,Y,scl*U(:,:,1),scl*V(:,:,1),...
                0,'Color',app.ArrowColor,'LineWidth',app.ArrowLineWidth);
        end

        function updateQuiverAppereance(app)
            app.hquiver.LineWidth = app.ArrowLineWidth;
            app.hquiver.Color     = app.ArrowColor;
        end

        function updateCellBoundaryAppereance(app)
            colormap(app.UIAxes2, [0 0 0; app.BoundaryColor]);
        end

        function colormapEditorChanged(app,src,evnt)
            disp('Colormap Changed')
            switch app.ColormapDropDown.Value
                case 'none'

                case 'cell_labels'

                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value,...
                        strrep({app.ColormapData.Name},'.bin','')));
                    app.ColormapData(curEntry).colormap = app.UIAxes.Colormap;
            end
        end

        function colormapLimitsChanged(app,src,evnt)
            disp('CLim Changed')
            switch app.ColormapDropDown.Value
                case 'none'

                case 'cell_labels'

                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value,...
                        strrep({app.ColormapData.Name},'.bin','')));
                    app.ColormapData(curEntry).caxis    = app.UIAxes.CLim;
            end
        end
        
        function result = driftCorrectionMode(app)
            answer = questdlg(sprintf(['Drift correction was enabled for this data. Would you like to:' ...
                '\n 1) Align the video and crop the non-overlapping areas.' ...
                '\n 2) Keep the video intact and the results may appear drifting.']), ...
            	'Displaying drift correction', ...
            	'Align the video','Do not correct drift','Align the video');
            % Handle response
            switch answer
                case 'Align the video'
                    result = 1;
                case 'Do not correct drift'
                    result = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            debugMode = 1;
            if debugMode
                % Sample data
%                 app.MainDir = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220712_164803_multipass\output\Pretreatment_Middle_Well__E_007_Tritc';
%                 app.VideoDir = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\Pretreatment_Middle\Well__E_007\Tritc';
                app.MainDir = 'G:\Shared drives\David_Ricardo_TFM_codes\TFM_DEMO_TEST\scan\contractility_run_20230319_162349\output\B003_r0004_c0005_beads_interleaved';
                app.VideoDir = 'G:\Shared drives\David_Ricardo_TFM_codes\TFM_DEMO_TEST\scan\B003_r0004_c0005\beads_interleaved';
                % End of sample data
            else
                app.CallingApp = mainapp;
                alias = app.CallingApp.UITable.Data.Alias{app.CallingApp.selectedRow};
                app.UIFigure.Name = alias;
                app.Rootdir = app.CallingApp.CurrentDirectoryEditField.Value;
                app.MainDir = fullfile(app.CallingApp.CurrentDirectoryEditField.Value,'output',alias);
                app.VideoDir = app.CallingApp.UITable.Data.Location{app.CallingApp.selectedRow};
            end
            % Initial state
            app.isDedrifted = 0;
            app.CurrentFrame = 1;
            app.FrameRate = app.PlaybackSpeedfpsSpinner.Value;
            app.BoundaryColor = [1 0 0];
            app.ArrowColor =  [255, 255, 0]/255;
            app.ArrowLineWidth = 0.75;
            loadData(app);
            % Refresh X-Y-time and color axes limits
            initializeAxes(app)
            initializePlot(app)

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            f = floor(app.FrameSlider.Value);
            app.CurrentFrame = f;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            if app.PlayButton.Value
                last_frame = app.CurrentFrame; % Get the last frame plotted,
                % this is to avoid restarting video at frame 1 when toggling between pause/play
                app.tStart = tic;
                app.FrameSlider.Enable = 'off';
                app.QuiverDropDown.Enable = 'off';
                app.ColormapDropDown.Enable = 'off';
                app.PlayButton.Text = 'Pause';
            end
            while app.PlayButton.Value
                app.FrameSlider.Value = app.CurrentFrame;
                tEnd = toc(app.tStart);
                theo_frame = 1 + floor(mod((last_frame + tEnd*app.FrameRate),app.Nframes));
                app.CurrentFrame = theo_frame;
                app.FrameSlider.Value = theo_frame;
                updateQuiverUV(app);
                updateColormap(app);
                drawnow;
            end
            app.PlayButton.Text = 'Play';
            app.FrameSlider.Enable = 'on';
            app.QuiverDropDown.Enable = 'on';
            app.ColormapDropDown.Enable = 'on';
        end

        % Value changed function: QuiverDropDown
        function QuiverDropDownValueChanged(app, event)
            value = app.QuiverDropDown.Value;
            updateQuiverAxes(app);
            updateQuiverUV(app);
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            updateColormapAxis(app);
            updateColormap(app);
        end

        % Value changed function: PlaybackSpeedfpsSpinner
        function PlaybackSpeedfpsSpinnerValueChanged(app, event)
            value = app.PlaybackSpeedfpsSpinner.Value;
            app.FrameRate = value;
        end

        % Value changed function: CellBoundariesCheckBox
        function CellBoundariesCheckBoxValueChanged(app, event)
            value = app.CellBoundariesCheckBox.Value;
            if value
                set(app.hboundaries,'Visible','on');
            else
                set(app.hboundaries,'Visible','off');
            end
        end

        % Value changed function: ArrowScaleSpinner
        function ArrowScaleSpinnerValueChanged(app, event)
            value = app.ArrowScaleSpinner.Value;
            updateQuiverAxes(app);
            updateQuiverUV(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            %             verticalScrollAmount = event.VerticalScrollAmount;
            %             verticalScrollCount = event.VerticalScrollCount;
            %             pos = app.UIFigure.CurrentPoint;
            %             hFound=[];
            %             for n=1:length(app.editableTags)
            %                 hTag = findobj(app.UIFigure,'nomenu','Tag', app.editableTags{n});
            %                 hPos = getpixelposition(hTag,true);  % recursive to find absoulute position
            %                 if pos(1)>hPos(1) && pos(1)<hPos(1)+hPos(3) && pos(2)>hPos(2) && pos(2)<hPos(2)+hPos(4)
            %                     hFound = hTag;
            %                     break
            %                 end
            %             end
            %             if isempty(hFound)
            %                 return
            %             end
            %             switch hFound.Type
            %                 case 'uispinner'
            %                     hFound.Value = hFound.Value - verticalScrollCount*hFound.Step;
            %                 case 'uislider'
            %                     step = 1;
            %                     val = hFound.Value - verticalScrollCount*step;
            %                     if val>=hFound.Limits(1) && val<=hFound.Limits(2)
            %                         hFound.Value = hFound.Value + verticalScrollCount*step;
            %                         app.CurrentFrame = round(hFound.Value);
            %                     end
            %                 otherwise
            %             end
            %             updateQuiverUV(app);
            %             updateColormap(app);
        end

        % Button pushed function: NextFrame
        function NextFrameButtonPushed(app, event)
            app.CurrentFrame = app.CurrentFrame+1;
            if app.CurrentFrame > app.Nframes
                app.CurrentFrame = 1;
            end
            app.FrameSlider.Value = app.CurrentFrame;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Button pushed function: PrevFrame
        function PrevFrameButtonPushed(app, event)
            app.CurrentFrame = app.CurrentFrame-1;
            if app.CurrentFrame < 1
                app.CurrentFrame = app.Nframes;
            end
            app.FrameSlider.Value = app.CurrentFrame;
            updateQuiverUV(app)
            updateColormap(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    NextFrameButtonPushed(app, event);
                case 'leftarrow'
                    PrevFrameButtonPushed(app, event);
            end
        end

        % Button pushed function: SaveVideoButton
        function SaveVideoButtonPushed(app, event)
            % Create a hidden figure with the dimensions of the target
            % quality
            switch app.ColormapDropDown.Value
                case 'none'
                    cdata = struct([]);
                case 'cell_labels'
                    cdata.plottype = 'cell_labels';
                    cdata.XData = [1,size(app.CellposeMaskRGB,2)];
                    cdata.YData = [1,size(app.CellposeMaskRGB,1)];
                    cdata.tvec  = 1;
                    cdata.Data  = app.CellposeMaskRGB;
                otherwise
                    curEntry = find(strcmp(app.ColormapDropDown.Value,...
                        strrep({app.Data.Name},'.bin','')));
                    cdata = app.Data(curEntry);
                    cdata.plottype = 'video';
                    cdata.CLim = cdata.caxis;
                    cdata.XData = [cdata.xvec(1) cdata.xvec(end)];
                    cdata.YData = [cdata.yvec(1) cdata.yvec(end)];
                    cdata.Data  = cdata.MAG;
                    cdata.colormap = app.UIAxes.Colormap;
            end
            if app.CellBoundariesCheckBox.Value
                cellboundaries = struct();
                cellboundaries.CData = app.hboundaries.CData;
                cellboundaries.AlphaData = app.hboundaries.AlphaData;
                cellboundaries.colormap = app.UIAxes2.Colormap;
                cellboundaries.CLim = [0 1];
            else
                cellboundaries = struct([]);
            end

            if ~strcmp(app.QuiverDropDown.Value,'none')
                curEntry = find(strcmp(app.QuiverDropDown.Value, ...
                    strrep({app.Data.Name},'.bin','')));
                quiverdata = app.Data(curEntry);
                quiverdata.scale = app.ArrowScaleSpinner.Value;
                quiverdata.color = app.ArrowColor;
                quiverdata.linewidth = app.ArrowLineWidth;
            else
                quiverdata = struct([]);
            end
            outputres = [app.UIAxes.Position(3),app.UIAxes.Position(4)];
            outputdir = app.MainDir;
            outputname = 'video.mp4';
            xl = app.UIAxes.XLim;
            yl = app.UIAxes.YLim;
            framerate = app.FrameRate;
            exportMovie(quiverdata,cdata,cellboundaries,outputres,framerate,outputdir,outputname,xl,yl)
        end

        % Menu selected function: ArrowLineWidthMenu
        function ArrowLineWidthMenuSelected(app, event)
            prompt = {'Enter Arrow Line Width:'};
            dlgtitle = 'Quiver Properties';
            dims = [1 35];
            definput = {num2str(app.ArrowLineWidth)};
            valid_input = 0;
            while ~valid_input
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                lw = str2num(answer{1});
                valid_input = ~isempty(lw);
            end
            app.ArrowLineWidth = lw;
            updateQuiverAppereance(app)
            drawnow
        end

        % Menu selected function: ArrowColorMenu
        function ArrowColorMenuSelected(app, event)
            c = uisetcolor;
            if length(c)==3
                app.ArrowColor = c;
            end
            updateQuiverAppereance(app)
            figure(app.UIFigure);
        end

        % Menu selected function: ShowLegendMenu
        function ShowLegendMenuSelected(app, event)
            if strcmp(app.ShowLegendMenu.Checked,'off')
                app.ShowLegendMenu.Checked = 'on';
                colorbar(app.UIAxes,'Visible','on')
                drawnow
            else
                app.ShowLegendMenu.Checked = 'off';
                colorbar(app.UIAxes,'Visible','off')
                drawnow
            end
        end

        % Menu selected function: ColormapEditorMenu
        function ColormapEditorMenuSelected(app, event)
            %             axes(app.UIAxes)
            colormapeditor(app.UIAxes)
        end

        % Menu selected function: CellBoundaryColorMenu
        function CellBoundaryColorMenuSelected(app, event)
            c = uisetcolor;
            if length(c)==3
                app.BoundaryColor = c;
            end
            updateCellBoundaryAppereance(app)
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.AppereanceMenu = uimenu(app.UIFigure);
            app.AppereanceMenu.Text = 'Appereance';
            app.ArrowColorMenu = uimenu(app.AppereanceMenu);
            app.ArrowColorMenu.MenuSelectedFcn = createCallbackFcn(app, @ArrowColorMenuSelected, true);
            app.ArrowColorMenu.Text = 'Arrow Color';
            app.ArrowLineWidthMenu = uimenu(app.AppereanceMenu);
            app.ArrowLineWidthMenu.MenuSelectedFcn = createCallbackFcn(app, @ArrowLineWidthMenuSelected, true);
            app.ArrowLineWidthMenu.Text = 'Arrow Line Width';
            app.ColormapEditorMenu = uimenu(app.AppereanceMenu);
            app.ColormapEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapEditorMenuSelected, true);
            app.ColormapEditorMenu.Text = 'Colormap Editor';
            app.ShowLegendMenu = uimenu(app.AppereanceMenu);
            app.ShowLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowLegendMenuSelected, true);
            app.ShowLegendMenu.Text = 'Show Legend';
            app.CellBoundaryColorMenu = uimenu(app.AppereanceMenu);
            app.CellBoundaryColorMenu.MenuSelectedFcn = createCallbackFcn(app, @CellBoundaryColorMenuSelected, true);
            app.CellBoundaryColorMenu.Text = 'Cell Boundary Color';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, 'gray')
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.ArrowScaleSpinnerLabel = uilabel(app.UIFigure);
            app.ArrowScaleSpinnerLabel.Text = {'Arrow Scale'; ''};
            app.ArrowScaleSpinner = uispinner(app.UIFigure);
            app.ArrowScaleSpinner.Step = 0.1;
            app.ArrowScaleSpinner.Limits = [0 Inf];
            app.ArrowScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ArrowScaleSpinnerValueChanged, true);
            app.ArrowScaleSpinner.Value = 3;
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Tag = 'tagFrameSlider';
            app.FrameSlider.Value = 1;
            app.QuiverDropDownLabel = uilabel(app.UIFigure);
            app.QuiverDropDownLabel.Text = 'Quiver';
            app.QuiverDropDown = uidropdown(app.UIFigure);
            app.QuiverDropDown.Items = {'none'};
            app.QuiverDropDown.ValueChangedFcn = createCallbackFcn(app, @QuiverDropDownValueChanged, true);
            app.QuiverDropDown.Value = 'none';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'none'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'none';
            app.CellBoundariesCheckBox = uicheckbox(app.UIFigure);
            app.CellBoundariesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CellBoundariesCheckBoxValueChanged, true);
            app.CellBoundariesCheckBox.Text = 'Cell Boundaries';
            app.CellBoundariesCheckBox.Value = true;
            app.PlaybackSpeedfpsSpinnerLabel = uilabel(app.UIFigure);
            app.PlaybackSpeedfpsSpinnerLabel.Text = 'Playback Speed (fps)';
            app.PlaybackSpeedfpsSpinner = uispinner(app.UIFigure);
            app.PlaybackSpeedfpsSpinner.Step = 0.1;
            app.PlaybackSpeedfpsSpinner.Limits = [0 Inf];
            app.PlaybackSpeedfpsSpinner.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedfpsSpinnerValueChanged, true);
            app.PlaybackSpeedfpsSpinner.Value = 30;
            app.PrevFrame = uibutton(app.UIFigure, 'push');
            app.PrevFrame.ButtonPushedFcn = createCallbackFcn(app, @PrevFrameButtonPushed, true);
            app.PrevFrame.Text = '<<';
            app.NextFrame = uibutton(app.UIFigure, 'push');
            app.NextFrame.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrame.Text = '>>';
            app.SaveVideoButton = uibutton(app.UIFigure, 'push');
            app.SaveVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVideoButtonPushed, true);
            app.SaveVideoButton.Text = 'Save Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = playerBeta5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end